% description: Model mosaics of arbitrary size of cones and rods that are interconnected via gap-junctions. Simulate the photoresponse in directly illuminated photoreceptors and model spread of signal
% across gap-junctions. Copyright 2024 Luca Della Santina.
classdef ConeRodModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeRodModelUIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        BatchUpdate           matlab.ui.control.Button
        btnInfo               matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        BatchSteps            matlab.ui.control.NumericEditField
        StepsEditFieldLabel   matlab.ui.control.Label
        BatchTo               matlab.ui.control.NumericEditField
        ToEditFieldLabel      matlab.ui.control.Label
        BatchFrom             matlab.ui.control.NumericEditField
        FromEditFieldLabel    matlab.ui.control.Label
        BatchSetting          matlab.ui.control.DropDown
        BatchRun              matlab.ui.control.Button
        BatchList             matlab.ui.control.ListBox
        BatchAddRange         matlab.ui.control.Button
        BatchClear            matlab.ui.control.Button
        BatchRemove           matlab.ui.control.Button
        BatchAdd              matlab.ui.control.Button
        tblParams             matlab.ui.control.Table
        PlotButton            matlab.ui.control.Button
        SolveButton           matlab.ui.control.Button
        PnlPlots              matlab.ui.container.Panel
        ContextMenuPlots      matlab.ui.container.ContextMenu
        Menu3Dplot            matlab.ui.container.Menu
    end

%% ConeRodModel- Model a network of interconnected cones and rod
%  Copyright (C) 2024 Luca Della Santina
%
%  This file is part of ConeRodModel
%
%  ObjectFinder is free software: you can redistribute it and/or modify
%  it under the terms of the GNU General Public License as published by
%  the Free Software Foundation, either version 3 of the License, or
%  (at your option) any later version.
%
%  This program is distributed in the hope that it will be useful,
%  but WITHOUT ANY WARRANTY; without even the implied warranty of
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%  GNU General Public License for more details.
%
%  You should have received a copy of the GNU General Public License
%  along with this program.  If not, see <https://www.gnu.org/licenses/>.
%    
    properties (Access = private)
        Version = '3.5'
        Params = struct % Constants and parameters of the model [IR,IC,CCjc,RRjc,RCjc,Rblocksjc,Rmc,Rmr,Cmc,Cmr,Er,Ec]
        Solution = struct % Solution of the model with current params
        Layout % Plotting area of the UI
    end
    
    methods (Static)
        function P = getDefaultParams
            % P is a struct contains all initialized parameters

            P = struct;
            P.Tmax = 1;     % End Time of the simulation in second
            P.Tdelta = 0.001; % Sampling interval is seconds
            P.CCjc = 1e-11; % Direct cone/cone coupling conductance in S
            P.RRjc = 1e-11; % Direct rod/rod coupling conductance in S
            P.RCjc = 1e-10; % rod/cone coupling conductance in S
            P.RCjcRandom = false; % Whether to randomize RCjc or keep constant
            P.RCjcRndMean = 1.2e-9; % Mean of normal distribution to pick random values from
            P.RCjcRndSD = 0.4e-9; % Standard deviation of normal distribution to pick random values from
            P.Rblocksjc = 1e-11; % rod/rod coupling; connections between blocks of rods in S
            P.NoiseConeAmp = 0.5e-12; % cone noise amplitude in A;
            P.NoiseRodAmp = 0.5e-12; % rod noise amplitude in A;
            P.Rmc = 2.4e9;  % cone membrane resistance in ohms
            P.Rmr = 2.4e9;  % rod membrane resistance in ohms
            P.Cmc = 5e-12;  % cone membrane capacitance in F
            P.Cmr= 5e-12;   % rod membrane capacitance in F
            P.Er = 0;       % rod resting membrane potential in V
            P.Ec = 0;       % cone resting membrane potential in V 
            P.SPR = -1e-12; % Single photon response
            P.t1 = 0.010;   % 1st time-constant of intensity1
            P.t2 = 0.060;   % 2nd time-constant of intensity1
            P.RefCone = 5;  % Reference cone for plotting results
            P.ConeRows = 3; % Number of rows of Cones
            P.ConeCols = 3; % Number of columns of cones
            P.RodRows = 8;  % Number of rows of rods (per cone)
            P.RodCols = 5;  % Number of columns of rods (per cone)
            P.RodsIllNum = 1; % Number of rods illuminated (per cone)
            P.RodsIllRandom = false; % 50 percent of rods randomly illuminated (per cone)
        end

        function D = getCurrentDateString
            % Returns the current date/time as a string, OS safe version
            % otherwise time includes ":" which is not allowred in
            % filennames on Windows OS

            D = char(datetime('now'));
            D = strrep(D, ':', '-');
        end
    end

    methods (Access = private)
        
        function dVdt = myode(app,t,V)
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            % Current Vector, interpolated current at time t for all cells
            I = zeros(nPR,1);
            for i = 1:nPR
                if i<=nCones
                    I(i) = interp1(app.Params.Itc,app.Params.IC(i,:),t); % Cones
                else
                    I(i) = interp1(app.Params.Itr,app.Params.IR(i,:),t); % Rods
                end
            end

            % Capacitance Matrix, storing the capacitance for each node
            C = zeros(nPR);
            for n=1:nPR
                if i<=nCones
                    C(n,n)=app.Params.Cmc;  % Cone membrane capacitance;
                else
                    C(n,n)=app.Params.Cmr; % Rod membrane capacitance;
                end
            end

            % Conductance Matrix
            S_total = app.Cone_graph;

            % Differential equation for voltage(t) = (S*V-I)/C
            dVdt=(C^-1)*(S_total*V-I); % Evaluate ODE at time t
            return
        end
        
        function S_total = Cone_graph(app)
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            S_Rod=app.Rod_Graph;

            % Cone connectivity matrix (each cone connected to the one at its right and to the one below)
            S_Cone = zeros(nCones,nCones);
            for cone = 1:nCones-1
                % Connect cones horizontally left-to-right (except last of each row)
                if mod(cone, app.Params.ConeCols) > 0
                    S_Cone(cone,cone+1) = 1;
                end

               % Connect cones vertically top-to-bottom (except last of each column)
                if cone <= (nCones - app.Params.ConeCols)  
                    S_Cone(cone, cone+app.Params.ConeCols)  = 1;
                end
            end
            S_Cone(S_Cone==1) = app.Params.CCjc; % Assign a CCjc all connected cones in S_Cone:


            % Compose S_Cone and S_Rod into a single block diagonal matrix
            S_total=blkdiag(S_Cone,S_Rod);

            % Connect each cone to blocks of rods
            for cone = 1:nCones
                for rod = 1:nRPC
                    S_total(cone,nCones + nRPC*(cone-1) +rod)=app.Params.RCjc(rod);
                    S_total(nCones + nRPC*(cone-1) +rod, cone)=app.Params.RCjc(rod);
                end
            end

            % TODO: Ask Christophe what is the reason for loop below, 
            % removing it does not change the connectivity between PRs
            % But it does add to the graph values in the center diagonal
            % Diagonal encodes connectivity between a PR and itself, any
            % meaning/effect in this model?

            % Making diagonal elements by summing all conductances in a row.
            for i=1:nPR
                for j=1:nPR
                    if i~=j
                        S_total(i,i)= S_total(i,j)+S_total(i,i);
                    end
                end
            end

            % Adding membrane conductance to diagonal elements
            for i=1:nCones  %for cones
                S_total(i,i)=-(S_total(i,i)+(1/app.Params.Rmc));
            end

            for i=nCones+1:nPR  %for rods
                S_total(i,i)=-(S_total(i,i)+(1/app.Params.Rmr));
            end
            
        end
        
        function S_Rod = Rod_Graph(app)
            % This function makes nCones blocks, each containing nRPC rods
            % S{cone} is the block of nRPC rods belonging to #cone
            % S{cone} is a nRPCxnRPC matrix encoding the condunctance of 
            %      Rod-Rod gap junctions
            %
            % S_Rod is a block diagonal matrix (nRPCxnCones,nRPCxnCones)
            %       where each element on the diagonal is S(cone}
            % 
            
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            S_Rod = zeros(nRPC*nCones, nRPC*nCones, 'double');

            % Define gap-junction connectivity within blocks of rods
            % Each cone is associated with a block of nRPC rods (arranged RodRowsxRodCols)
            for cone = 1:nCones
                cone_idx = nRPC*(cone-1)+1;
                for rod = 1:nRPC-1
                    % Horizontal gap junction resistance from RRjc
                    if mod(rod,app.Params.RodCols) > 0
                        S_Rod(cone_idx+rod-1, cone_idx+rod) = app.Params.RRjc;
                    end

                    % Vertical gap junction resistance from RRjc
                    if rod <= (nRPC-app.Params.RodCols)
                        S_Rod(cone_idx+rod-1, cone_idx+rod+app.Params.RodCols-1) = app.Params.RRjc;
                    end
                end
            end

            % Define gap-junction connectivity between blocks of rods
            for cone = 1:nCones-1
                S_Rod_Pos = cone*nRPC;

                % connect horizontal matrix blocks from left to right (except last element of each row)
                if mod(cone,app.Params.ConeCols) > 0
                    for rod = 1:app.Params.RodRows
                        S_Rod((cone-1)*nRPC+rod*app.Params.RodCols, S_Rod_Pos+rod*app.Params.RodCols-app.Params.RodCols+1) = app.Params.Rblocksjc;
                    end
                end

                % connect vertical matrix blocks from top to bottom (except last element of each column)
                if cone <= (nCones - app.Params.ConeCols)
                    for rod = nRPC-app.Params.RodCols+1:nRPC
                        S_Rod((cone-1)*nRPC+rod, (cone+app.Params.ConeCols-1)*nRPC+rod-app.Params.RodCols) = app.Params.Rblocksjc;
                    end                    
                end
            end            
        end
        
        function P = initializeModel(app)

            % Output P struct contains all initialized parameters
            UserP = app.getUserParams;

            P = struct;
            P.Rblocksjc = UserP.Rblocksjc; % rod/rod coupling; connections between blocks of rods in S
            P.Rmc = UserP.Rmc; % cone membrane resistance in ohms
            P.Rmr = UserP.Rmr; % rod membrane resistance in ohms
            P.Cmc = UserP.Cmc; % cone membrane capacitance in F
            P.Cmr= UserP.Cmr; % rod membrane capacitance in F
            P.Er = UserP.Er;  % rod resting membrane potential in V
            P.Ec = UserP.Ec;  % cone resting membrane potential in V  
            P.SPR = UserP.SPR; % Single photon response
            P.t1 = UserP.t1;% 1st time-constant of intensity1
            P.t2 = UserP.t2;% 1st time-constant of intensity1

            % Parameters introduced after v3.0, use default if missing
            if isfield(UserP, 'NoiseConeAmp') % rod noise amplitude;
                P.NoiseConeAmp = UserP.NoiseConeAmp;
            else
                P.NoiseConeAmp = 0.5e-12; 
            end

            if isfield(UserP, 'NoiseRodAmp') % rod noise amplitude;
                P.NoiseRodAmp = UserP.NoiseRodAmp;
            else
                P.NoiseRodAmp = 0.5e-12; 
            end

            if isfield(UserP, 'Tmax') % End time of the simulation in s
                P.Tmax = UserP.Tmax;
            else
                P.Tmax = 1; 
            end            

            if isfield(UserP, 'Tdelta') % Sampling time in s
                P.Tdelta = UserP.Tdelta;
            else
                P.Tdelta = 1; 
            end            

            % Parameters introduces after v2.0, use default if missing        
            if isfield(UserP, 'RefCone') % Reference cone number
                P.RefCone = UserP.RefCone;
            else
                P.RefCone = 5;
            end
            if isfield(UserP, 'ConeRows') % Number of cones modeled
                P.ConeRows = UserP.ConeRows;
            else
                P.ConeRows = 3;
            end
            if isfield(UserP, 'ConeCols') % Number of cones modeled
                P.ConeCols = UserP.ConeCols;
            else
                P.ConeCols = 3;
            end
            if isfield(UserP, 'RodRows') % Number of cones modeled
                P.RodRows = UserP.RodRows;
            else
                P.RodRows = 8;
            end
            if isfield(UserP, 'RodCols') % Number of cones modeled
                P.RodCols = UserP.RodCols;
            else
                P.RodCols = 5;
            end

            if isfield(UserP, 'RCjcRndMean') % Number of cones modeled
                P.RCjcRndMean = UserP.RCjcRndMean;
            else
                P.RCjcRndMean = 1.2e-9; % Mean of normal distribution to pick random values from
            end

            if isfield(UserP, 'RCjcRndSD') % Number of cones modeled
                P.RCjcRndSD = UserP.RCjcRndSD;
            else            
                P.RCjcRndSD = 0.4e-9; % Standard deviation of normal distribution to pick random values from
            end

            if isfield(UserP, 'RodsIllNum') % Number of cones modeled
                P.RodsIllNum = UserP.RodsIllNum;
            else            
                P.RodsIllNum = 1; % Number of rods illuminated per cone
            end
            
            if isfield(UserP, 'RodsIllRandom') % Random chance of being illuminated
                P.RodsIllRandom = UserP.RodsIllRandom;
            else            
                P.RodsIllRandom = false; % Random chance of being illuminated (50% chance per rod)
            end

            nCones = P.ConeRows*P.ConeCols; % Total number of cones
            nRPC = P.RodRows*P.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors

            % IR, rod photocurrent (all rods treated as a group)
            % rods connected to cone 5 are elements #170-209 (or rods #160-199)

            P.Itr=linspace(0,P.Tmax,floor(P.Tmax/P.Tdelta)); % from t=0 to t=1s, 1000 points
            P.IR= zeros(nPR,floor(P.Tmax/P.Tdelta)); %reset to 0
            P.Illuminated= zeros(nPR,1); %reset to 0

            % Determine which rod is illuminated (P.Illuminated = 1)
            for rod = nCones+1:nRPC:nPR
                IllumRPC = zeros(nRPC,1); % Default is no illumination, just random noise
                if UserP.RodsIllRandom
                    idxs = randperm(length(IllumRPC)); % Randomize indeces of illuminated rods
                    idxs = idxs(1:UserP.RodsIllNum);
                else
                    idxs = 1:UserP.RodsIllNum; % Pick the first RodsIllNum rods
                end
                IllumRPC(idxs) = 1;                    
                P.Illuminated(rod:rod+nRPC-1) = IllumRPC; % Store illumination status of each photoreceptor in P.Illuminated
            end

            % IR for all rods (either illuminated + noise or just noise)
            for rod = nCones+1:nPR
                noisy_r=(P.NoiseRodAmp)*randn(1,floor(P.Tmax/P.Tdelta)); %randn returns random value around 0, Gaussian distribution with sd=1                
                myHeaviside = @(V)(1+sign(V))/2; %frames single photon response at t=1s
                P.IR(rod,:) = (noisy_r)-1.*P.Illuminated(rod).*(UserP.SPR.*(myHeaviside(P.Itr-0.5).*(1-exp(-(P.Itr-0.5)./UserP.t1))-myHeaviside(P.Itr-0.58).*(1-exp(-(P.Itr-0.58)./UserP.t2))));
            end
            
            % IC for all cones, cone photocurrent
            P.Itc=linspace(0,P.Tmax,floor(P.Tmax/P.Tdelta)); % from t=0 to t=1s, 1000 points
            P.IC = zeros(nCones,floor(P.Tmax/P.Tdelta)); %reset to 0
            for i = 1:nCones
                noisy_c = (P.NoiseConeAmp)*randn(1,floor(P.Tmax/P.Tdelta)); % randn returns random value around 0, Gaussian distribution with sd=1
                P.IC(i,:) = (noisy_c); % No single photon response in the cones, just noise
            end

            P.CCjc = UserP.CCjc; % CCjc cone/cone coupling conductance in S
            P.RRjc = UserP.RRjc; % RRjc rod/rod coupling conductance in S
            P.RCjc = zeros(nRPC); % RCjc rod/cone coupling conductance in S

            for i=1:nRPC % choose from the following options:

                if UserP.RCjcRandom
                    % random coupling (mean +/- sd)
                    a = UserP.RCjcRndSD; %standard deviation of normally distributed data
                    b = UserP.RCjcRndMean; %mean (S)
                    y = a.*randn(nRPC,1)+b;
                    P.RCjc(i,:)=(y);
                else
                    % constant conductance for all rods
                    for j=1:nRPC
                        P.RCjc(j) = UserP.RCjc;
                    end
                    %P.RCjc(j) = UserP.RCjc;
                    
                    %some rods coupled to cones and others not <-- Ask christope which ones are which
                    %for j=1:40
                    %    P.RCjc(j) = UserP.RCjc;
                    %end
                    %for i=20:40
                    %    RCjc(i) = 1200e-12;
                    %end
                end
            end

        end
        
        function saveSolution(app, S)
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            % Saving the model output in Excel file
            filename = ['Model_' app.getCurrentDateString '_NodeVoltage.xlsx'];
            output = [S.t, S.V];
            rowsnum = nPR+1; % Number or rows in the output table
            V_Cell = cell(rowsnum,1);
            for i = 1:rowsnum
                if i==1
                    V_Cell{1,1}={'time'};
                elseif i>1 && i<=nCones+1
                    V_Cell{i,1}=sprintf('cone %d',i-1);
                elseif i>nCones+1 && i<=nPR+1
                    V_Cell{i,1}=sprintf('rod %d',i-1);
                end
            end
            T=table(V_Cell,output');
            writetable(T,filename)
        end
        
        function P = getUserParams(app)
            C = table2cell(app.tblParams.Data);
            P = cell2struct(C(:,2),C(:,1),1);
        end
        
        function plotResults(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end

            if isfield(app.Params, 'RefCone')
                RefCone = app.Params.RefCone;
            else
                RefCone = 5;
            end

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            if RefCone > nCones
                msgbox("Selected reference cone does not exist","Error","error","modal");
                return
            end

            app.Layout = tiledlayout(app.PnlPlots, 5, 3, "TileSpacing","tight","Padding","tight");
            
            % Plot the network graph
            pb.Message = 'Connectivity graph';
            ax = nexttile(app.Layout, [2 3]);

            % elements #1-9 are cones, #10-369 are rods, rods that connects to cone #5
            % are elements #170-209 (rods #160-199)
            S_total = triu(app.Cone_graph, 1); % Extract upper triangular part
            cm = S_total + S_total.'; % Add the transpose to make it symmetric
            G = graph(cm);
            p = plot(ax, G, 'Layout','force');

            % Set the z-coordinates for edges based on the connectivity matrix
            for i = 1:G.numnodes
                for j = i+1:G.numnodes
                    if cm(i, j) ~= 0
                        p.ZData([i j]) = [cm(i, j) cm(i, j)]; % Use edge lengths as z-coordinates
                    end
                end
            end            

            % Change color for markers based on cell identity
            Color_Cones = [1 0 0];
            Color_RefCone = [0.9290 0.6940 0.1250];
            Color_Rods = [0 0.1 0.5];
            Color_RodsIlluminated = [0 0.5 1];
            
            node_colors = zeros(sum(p.ZData>0), 3);
            node_colors(1:nCones, :) = repmat(Color_Cones, nCones, 1); % Set color of cones to red
            node_colors(RefCone, :) = Color_RefCone; % Set color of reference cone to orange
            node_colors(nCones+1:end, :) = repmat(Color_Rods, nPR-nCones, 1); % Set color of rods to light blue
            
            rods = find(app.Params.Illuminated);
            for rod = 1:numel(rods)
                node_colors(rods(rod), :) = Color_RodsIlluminated;
            end
            p.NodeColor = node_colors;
            
            p.MarkerSize = 8; % Set the marker size as desired
            p.EdgeColor = [0.5 0.5 0.5]; % Set the connection lines to grey
            if app.Menu3Dplot.Checked
                view(ax, 30, 40); % Adjust azimuth and elevation angles as needed
            end
            % Set a custom legend for the connectivity plot
            hold(ax,"on")
            h = zeros(4, 1);
            h(1) = plot(ax,NaN,NaN, '.', 'Color', Color_Cones,   'MarkerSize',24);
            h(2) = plot(ax,NaN,NaN, '.', 'Color', Color_RefCone, 'MarkerSize',24);
            h(3) = plot(ax,NaN,NaN, '.', 'Color', Color_Rods,    'MarkerSize',24);
            h(4) = plot(ax,NaN,NaN, '.', 'Color', Color_RodsIlluminated,    'MarkerSize',24);
            legend(h, {'Cone','Reference Cone','Rod', 'Rod Illuminated'}, 'Location', "northwest", "Box", "off");
            
            % rod photocurrent function IR0
            pb.Message = 'Photocurrents of all rods';            
            ax = nexttile(app.Layout);
            plot(ax, app.Params.Itr,app.Params.IR);     
            title(ax, 'Photocurrent of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Current (A)')
            ylim(ax, [-4e-12 4e-12])
            grid(ax, "on")

            % Plot voltage of all rods
            pb.Message = 'Membrane potential of all rods';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=nCones+1:nPR
                plot(ax, app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, 'Vm  of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax,"on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Er,0.004+app.Params.Er])

            % Plot individual rods responses
            pb.Message = 'Response amplitude of all rods';            
            ax = nexttile(app.Layout);
            hold(ax,"on") 
            counter = 0;
            Vm = zeros(numel(nCones+1:nPR),3);
            for i = nCones+1:nPR
                counter = counter+1; 
                Vm(counter,1) = i;
                Vm(counter,2) = app.Solution.V(570,i);
                Vm(counter,3) = ismember(i, (RefCone-1)*nRPC+nCones+1 : RefCone*nRPC+nCones);           
                scatter(ax, 1, app.Solution.V(570,i),'jitter','on','jitterAmount',0.1);
            end
            ax.YDir = "reverse";
            title(ax, 'Response of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Response (V)')
            grid(ax, "on")
            axis(ax, [0.85,1.15,-0.004+app.Params.Er,0.004+app.Params.Er])
            text(ax,0.87,0.003, ['mean ± sd = ' num2str(mean(Vm(:,2)),3) ' ± ' num2str(std(Vm(:,2)),3)] );
            
            % Save values in a table the model output in Excel file
            filename = ['Model_' app.getCurrentDateString '_RodsResponseAmp.xlsx'];
            T = table;
            T.Rod = Vm(:,1);
            T.ResponseV = Vm(:,2);
            T.ConnectedToRefCone = Vm(:,3);
            writetable(T,filename)

            % cone photocurrent function
            pb.Message = 'Photocurrent of all cones';            
            ax = nexttile(app.Layout);
            plot(ax, app.Params.Itc, app.Params.IC);
            title(ax, 'Photocurrent of all cones')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Current (A)')
            ylim(ax, [-4e-12 4e-12])
            grid(ax,"on")

            % Plot membrane voltage of all cones
            pb.Message = 'Membrane voltage of all cones';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=1:nCones
                plot(ax, app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, 'Vm  of all cones')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Ec,0.004+app.Params.Ec])

            % Plot membrane voltage of the rods illuminated
            pb.Message = 'Membrane voltage of the rods illuminated';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i = nCones+1:nPR
                if app.Params.Illuminated(i)
                    plot(ax, app.Solution.t, app.Solution.V(:,i));
                end
            end
            title(ax, ['Vm of illuminated rods (n=' num2str(sum(app.Params.Illuminated)) ')'])
            xlabel(ax, 'Time(s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Er,0.004+app.Params.Er])

            % Plot Vm of reference cone
            pb.Message = 'Membrane potential of reference cone';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            plot(ax, app.Solution.t,app.Solution.V(:,RefCone));
            title(ax, ['Vm of reference cone #' num2str(RefCone)])
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Ec,0.004+app.Params.Ec])

            % Plot voltage of all rods coupled to reference cone
            pb.Message = 'Membrane potential of all rods connected to reference cone'; 
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=(RefCone-1)*nRPC+nCones+1 : RefCone*nRPC+nCones
                plot(ax,app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, ['Vm of rods coupled to reference cone #' num2str(RefCone)])
            xlabel(ax, 'Time(s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Er,0.004+app.Params.Er])
            
            % Plot individual responses of rods connected to reference cone
            pb.Message = 'responses of rods connected to reference cone';
            ax = nexttile(app.Layout);
            hold(ax,"on")
            Vm = zeros(numel(nCones+1:nPR),2);
            counter = 0;
            for i=(RefCone-1)*nRPC+nCones+1 : RefCone*nRPC+nCones
                counter = counter+1; 
                Vm(counter,1) = i;
                Vm(counter,2) = app.Solution.V(570,i);                
                scatter(ax, 1, app.Solution.V(570,i),'jitter','on','jitterAmount',0.1);
            end
            ax.YDir = "reverse";
            title(ax, ['Response of rods coupled to reference cone #' num2str(RefCone)])
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Response amplitude (V)')
            grid(ax, "on")
            axis(ax, [0.85,1.15,-0.004+app.Params.Er,0.004+app.Params.Er])
            text(ax,0.87,0.003, ['mean ± sd = ' num2str(mean(Vm(:,2)),3) ' ± ' num2str(std(Vm(:,2)),3)] );

            
            pb.Message = 'Saving plots as PDF';
            exportgraphics(app.Layout, ['Model_' app.getCurrentDateString '_Plots.pdf']);

            pb.Message = 'Saving results spreadsheet';
            app.saveSolution(app.Solution);
        end
        
        
        function S = solveModel(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end            
            
            pb.Message = 'Initializing model...';
                        
            S = struct;
            S.Params = app.initializeModel;
            S.ParamsTable = app.tblParams.Data;
            app.Params = S.Params;

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            if app.Params.RefCone > nCones
                msgbox("Selected reference cone does not exist","Error","error","modal");
                return
            end
            
            pb.Message = 'Solving differential equation, please wait...';

            % Initial parameters for the ODE solver
            % see myode() function for the differential equation to solve

            tspan = 0:S.Params.Tdelta:S.Params.Tmax; % explore from t=0 to t=Tmax in Tdelta intervals (seconds)
            V0 = zeros(nPR,1); % All rods and cones populations
            % V0(1:nCones,1) = S.Params.Ec; % Resting potential for cones
            % V0(nCones+1:nPR,1) = S.Params.Er; % Resting potential for rods

            options = odeset('RelTol',1e-3, 'Maxstep', 1); % resolution, Maxstep to set at 1 for noise analysis
            [S.t,S.V] = ode15s(@(t,V) app.myode(t,V), tspan, V0, options);

            pb.Message = 'Correct voltages by resting membrane potential';
            for i=1:nPR
                if i>=1 && i<=nCones
                    S.V(:,i) = S.V(:,i)+S.Params.Ec;
                else
                    S.V(:,i) = S.V(:,i)+S.Params.Er;
                end
            end
            
            pb.Message = 'Saving plots as PDF';
            save(['Model_' app.getCurrentDateString '.mat'], "S");            
        end
        
        function I = calcIrod(app, ResponseType)
            switch ResponseType
                case 'SPR' % Single-photon response
                    noisy_r=(0.5e-12)*randn(1,1000); %randn returns random value around 0, Gaussian distribution with sd=1                
                    myHeaviside = @(V)(1+sign(V))/2; %frames single photon response at t=1s
                    P.IR(rod,:) = (noisy_r)-1.*P.Illuminated(rod).*(UserP.SPR.*(myHeaviside(P.Itr-0.5).*(1-exp(-(P.Itr-0.5)./UserP.t1))-myHeaviside(P.Itr-0.58).*(1-exp(-(P.Itr-0.58)./UserP.t2))));            
                case 'LR' % Full light reponse model
                    % Parameters of the rod cell (Liu & Kourennyi 2004)
                    C_rod = 30; 			% pF capacitance
                    EKv_rod = -74; 			% mV
                    EKx_rod = -74; 			% mV
                    Eh_rod = -32;			% mV
                    EKCa_rod = -74;			% mV
                    ECa_rod = 40; 			% mV
                    ECl_rod = -20; 			% mV
                    EL_rod = -74; 			% mV 
                    
                    gKx_rod = 1.04;			% nS 
                    gKv_rod = 10; 			% nS
                    gh_rod = 2.5; 			% nS
                    gKCa_rod = 5; 			% nS
                    gCa_rod = 4; 			% nS
                    gCl_rod = 1.3; 			% nS
                    gL_rod = 0.52; 			% nS
                    
                    Cainf_rod = 5e-5; 		% mM
                    K_KCa_rod = 0.00032; 	% mM
                    K_Cl_rod = 0.0015;		% mM

                    % Intracellular calcium concentration (Liu & Kourennyi 2004)
                    radius_rod = 7.8; 
                    volume_rod = (4/3)*pi*radius_rod^3; % um^3
                    z = 2; 								% valence
                    tau_Cai_rod = 20;		% ms
                    
                    % Conversion factor for intracellular calcium concentration (Liu & Kourennyi 2004)
                    fI = 10e-6; 		    % ICa in pA # was 10e-3; conversion factor for ICa in nA 
                    fF = 10e9; 		        % ICa in pA ==> F in pC / mmol
                    fv = 10e-15; 	        % L ==> umeter**3

                    % Noise
                    sigma_noise_rod = 0.5;  % pA 	0.05 pA
                    tau_noise_rod = 1;	    % ms
                    
                    % photocurrent
                    Idark_rod = -40;        % pA
                    b1 = 1000;	            % ms	# 1000 * ms
                    b2 = 1500;	            % ms	# 1500 * ms # 1200 * ms
                    t1 = 100; 	            % ms 	# second # ms # C. Ribelayga # 100 * ms # 20 * ms
                    t2 = 100; 	            % ms 	# second # ms # 100 * ms # 500 * ms

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the dialog on main window
            posParent = get(0,'ScreenSize');
            pos = app.ConeRodModelUIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.ConeRodModelUIFigure.Position = pos;        

            app.ConeRodModelUIFigure.Name = [app.ConeRodModelUIFigure.Name ' ' app.Version];
            
            % Initialize table of parameters with default values
            P = app.getDefaultParams;
            t = table(fieldnames(P), struct2cell(P));
            app.tblParams.Data = t;
            app.BatchSetting.Items = t{:,1};
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Solving Model', 'Indeterminate','on');

            app.Solution = app.solveModel(pb);
            app.PlotButton.Enable = "on";
            close(pb);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            app.plotResults(pb);           
            close(pb);
        end

        % Button pushed function: BatchRemove
        function BatchRemoveButtonPushed(app, event)
            idx = app.BatchList.ValueIndex;
            app.BatchList.Items(idx) = [];
            app.BatchList.ItemsData(idx) = [];
            if isempty(app.BatchList.Items)
                app.BatchRemove.Enable = "off";
                app.BatchClear.Enable = "off";
                app.BatchRun.Enable = "off";
                app.BatchUpdate.Enable = "off";
            end
        end

        % Button pushed function: BatchClear
        function BatchClearButtonPushed(app, event)
            switch uiconfirm(app.ConeRodModelUIFigure, 'Remove all items from list?','Delete items','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    app.BatchList.Items = {};
                    app.BatchList.ItemsData = [];
                    app.BatchRemove.Enable = "off";
                    app.BatchClear.Enable = "off";
                    app.BatchRun.Enable = "off";
                    app.BatchUpdate.Enable = "off";
            end
        end

        % Button pushed function: BatchAdd
        function BatchAddButtonPushed(app, event)
            JobTitle = char(app.getCurrentDateString);
            L = app.tblParams.Data; % List of user-selected Settings
         
            if isempty(app.BatchList.Items)
                app.BatchList.Items = {JobTitle};
                app.BatchList.ItemsData = {L};
            else
                app.BatchList.Items{end+1} = JobTitle;
                app.BatchList.ItemsData(end+1) = {L};
            end
            app.BatchRemove.Enable = "on";
            app.BatchClear.Enable = "on";
            app.BatchRun.Enable = "on";
        end

        % Double-clicked callback: BatchList
        function BatchListDoubleClicked(app, event)
            item = app.BatchList.Value;
            if ~isempty(item)
                app.tblParams.Data = item;
            end
        end

        % Button pushed function: BatchAddRange
        function BatchAddRangeButtonPushed(app, event)
            if app.BatchFrom.Value >= app.BatchTo.Value
                msgbox("Invalid range, please check the typed values.","Error","error");
                return
            end

            JobTitle = char(app.getCurrentDateString);
            L = app.tblParams.Data; % List of user-selected Settings
            S = app.BatchSetting.Value;
            Sidx = find(strcmp(S, L{:,1}),1);

            % Create several jobs for the range of values explored
            for V = app.BatchFrom.Value:app.BatchSteps.Value:app.BatchTo.Value                
                JobTitleStep = [JobTitle ' ' S '=' num2str(V)];
                Lstep = L;
                Lstep{Sidx,2} = {V};
                                
                if isempty(app.BatchList.Items)
                    app.BatchList.Items = {JobTitleStep};
                    app.BatchList.ItemsData = {Lstep};
                else
                    app.BatchList.Items{end+1} = JobTitleStep;
                    app.BatchList.ItemsData(end+1) = {Lstep};
                end
                
            end

            app.BatchRemove.Enable = "on";
            app.BatchClear.Enable = "on";
            app.BatchRun.Enable = "on";            
        end

        % Button pushed function: BatchRun
        function BatchRunButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Batch processing:','Value', 0, 'Cancelable', 'on');

            for i = 1:numel(app.BatchList.Items)
                pb.Title = ['Batch processing: Model ' num2str(i) ' of ' num2str(numel(app.BatchList.Items))];
                pb.Value = i/numel(app.BatchList.Items);                
                app.BatchList.ValueIndex = i;
                app.tblParams.Data = app.BatchList.Value;
                app.Solution = app.solveModel(pb);
                app.plotResults(pb);

                if pb.CancelRequested
                    break;
                end                
            end

            close(pb);            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Supported formats (*.mat)'; '*.mat','Models (*.mat)'}, 'Select model', 'MultiSelect', 'off');
            
            app.ConeRodModelUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ConeRodModelUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if fName == 0
                return
            end
            
            % Read Simple Neurite Skeleton if available in the I folder
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Importing preset...','Indeterminate', 'on');
            pb.Message = ['Loading model from ' fName];
            Model = load(fullfile(PathName, fName),"S");
            app.Solution = Model.S;
            app.Params = Model.S.Params;
            app.tblParams.Data = Model.S.ParamsTable;
            app.PlotButton.Enable = "on";
            close(pb);            
        end

        % Menu selected function: Menu3Dplot
        function Menu3DplotSelected(app, event)
            app.Menu3Dplot.Checked = ~app.Menu3Dplot.Checked;
        end

        % Button pushed function: btnInfo
        function btnInfoButtonPushed(app, event)
            Msg = {'\bf Cone-rod gap junction model parameters:';
                   '\rm ';
                   '\bf Tmax \rm End time of the simulation in seconds';
                   '\bf Tdelta \rm Sampling time in seconds';                   
                   '\bf CCjc \rm Cone-Cone coupling conductance in S';
                   '\bf RRjc \rm Rod-Rod coupling conductance in S';
                   '\bf RCjc \rm Rod-Cone coupling conductance in S';
                   '\bf RCjcRandom \rm Enabled use random conductances for RCjc';
                   '\bf RCjcRndMean \rm Mean of the normal distribution to sample random RCjc';
                   '\bf RCjcRndSD \rm S.D. of the normal distribution to sample random RCjc';
                   '\bf Rblocksjc \rm Coupling conductance between blocks of rods in S ';
                   '\bf NoiseRodAmp \rm Noise current amplitude for cones in A';
                   '\bf NoiseRodAmp \rm Noise current amplitude for rods in A';
                   '\bf Rmc \rm Cone membrane resistance in Ohm';
                   '\bf Rmr \rm Rod membrane resistance in Ohm';
                   '\bf Cmc \rm Cone membrane capacitance in F';
                   '\bf Cmr \rm Rod membrane capacitance in F';
                   '\bf Er \rm Rod resting membrane potential in V';
                   '\bf Ec \rm Cone resting membrane potential in V';
                   '\bf SPR \rm Single photon response of rods in A';
                   '\bf t1 \rm First time contstant (rise phase) of rod SPR in s';
                   '\bf t2 \rm Second time contstant (decay phase) of rod SPR in s';
                   '\bf RefCone \rm Reference cone number';
                   '\bf ConeRows \rm Number of rows of cones';
                   '\bf ConeCols \rm Number of columns of cones';
                   '\bf RodRows \rm Number of rows of rods (per cone)';
                   '\bf RodCols \rm Number of columns of rods (per cone)';
                   '\bf RodsIllNum \rm Number of illuminated rods (per cone)';
                   '\bf RodsIllRandom \rm Randomize the position of illuminated rods'
                   '\rm ';
                   '\rm Licensed under GNU General Public License v3'
                   '\rm Copyright (C) 2024 Luca Della Santina'
                   };

            Opts.Interpreter = 'tex';
            Opts.WindowStyle = 'non-modal';
            Opts.icon = 'help';

            msgbox(Msg, "Model parameters", Opts);
        end

        % Button pushed function: BatchUpdate
        function BatchUpdateButtonPushed(app, event)
            % Update selected batch item with current settings
            L = app.tblParams.Data; % List of user-selected Settings
            app.BatchList.ItemsData(app.BatchList.ValueIndex) = {L};
        end

        % Value changed function: BatchList
        function BatchListValueChanged(app, event)
            value = app.BatchList.Value;
            if ~isempty(value)
                app.BatchUpdate.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConeRodModelUIFigure = uifigure('Visible', 'off');
            app.ConeRodModelUIFigure.Name = 'Cone-Rod Model';
            app.GridLayout = uigridlayout(app.ConeRodModelUIFigure);
            app.PnlPlots = uipanel(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.SolveButton = uibutton(app.Panel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-solve.png');
            app.SolveButton.Tooltip = {'Solve differential equations of rod-cone connectivity'};
            app.SolveButton.Text = 'Solve';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-graph.png');
            app.PlotButton.Enable = 'off';
            app.PlotButton.Tooltip = {'Plot results of the model for cones and rods'};
            app.PlotButton.Text = 'Plot';
            app.tblParams = uitable(app.Panel);
            app.tblParams.ColumnName = {'Setting'; 'Value'};
            app.tblParams.RowName = {};
            app.tblParams.ColumnSortable = [true false];
            app.tblParams.ColumnEditable = [false true];
            app.BatchAdd = uibutton(app.Panel, 'push');
            app.BatchAdd.ButtonPushedFcn = createCallbackFcn(app, @BatchAddButtonPushed, true);
            app.BatchAdd.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-add.png');
            app.BatchAdd.Tooltip = {'Add current settings to batch list'};
            app.BatchAdd.Text = '';
            app.BatchRemove = uibutton(app.Panel, 'push');
            app.BatchRemove.ButtonPushedFcn = createCallbackFcn(app, @BatchRemoveButtonPushed, true);
            app.BatchRemove.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-remove.png');
            app.BatchRemove.Enable = 'off';
            app.BatchRemove.Tooltip = {'Remove selected batch item from list'};
            app.BatchRemove.Text = '';
            app.BatchClear = uibutton(app.Panel, 'push');
            app.BatchClear.ButtonPushedFcn = createCallbackFcn(app, @BatchClearButtonPushed, true);
            app.BatchClear.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-clear.png');
            app.BatchClear.Enable = 'off';
            app.BatchClear.Tooltip = {'Remove all batch items from list'};
            app.BatchClear.Text = '';
            app.BatchAddRange = uibutton(app.Panel, 'push');
            app.BatchAddRange.ButtonPushedFcn = createCallbackFcn(app, @BatchAddRangeButtonPushed, true);
            app.BatchAddRange.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-add-range.png');
            app.BatchAddRange.Tooltip = {'Add the specified setting''s range to batch list'};
            app.BatchAddRange.Text = '';
            app.BatchList = uilistbox(app.Panel);
            app.BatchList.Items = {};
            app.BatchList.ValueChangedFcn = createCallbackFcn(app, @BatchListValueChanged, true);
            app.BatchList.Tooltip = {'List of batch processing jobs'};
            app.BatchList.DoubleClickedFcn = createCallbackFcn(app, @BatchListDoubleClicked, true);
            app.BatchList.Value = {};
            app.BatchRun = uibutton(app.Panel, 'push');
            app.BatchRun.ButtonPushedFcn = createCallbackFcn(app, @BatchRunButtonPushed, true);
            app.BatchRun.Icon = fullfile(pathToMLAPP, 'icons', '48px-robot.png');
            app.BatchRun.Enable = 'off';
            app.BatchRun.Tooltip = {'Run batch jobs from list'};
            app.BatchRun.Text = 'Process batch';
            app.BatchSetting = uidropdown(app.Panel);
            app.BatchSetting.Items = {};
            app.BatchSetting.Value = {};
            app.FromEditFieldLabel = uilabel(app.Panel);
            app.FromEditFieldLabel.Text = 'From';
            app.BatchFrom = uieditfield(app.Panel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.Panel);
            app.ToEditFieldLabel.Text = 'To';
            app.BatchTo = uieditfield(app.Panel, 'numeric');
            app.StepsEditFieldLabel = uilabel(app.Panel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.BatchSteps = uieditfield(app.Panel, 'numeric');
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-open.png');
            app.LoadButton.Tooltip = {'Load model settings from disk'};
            app.LoadButton.Text = 'Load';
            app.btnInfo = uibutton(app.Panel, 'push');
            app.btnInfo.ButtonPushedFcn = createCallbackFcn(app, @btnInfoButtonPushed, true);
            app.btnInfo.Icon = fullfile(pathToMLAPP, 'icons', '48px-info.png');
            app.btnInfo.Tooltip = {'Information on the model''s parameters'};
            app.btnInfo.Text = '';
            app.BatchUpdate = uibutton(app.Panel, 'push');
            app.BatchUpdate.ButtonPushedFcn = createCallbackFcn(app, @BatchUpdateButtonPushed, true);
            app.BatchUpdate.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-refresh.png');
            app.BatchUpdate.Enable = 'off';
            app.BatchUpdate.Tooltip = {'Update selected batch item with current settings '};
            app.BatchUpdate.Text = '';
            app.ContextMenuPlots = uicontextmenu(app.ConeRodModelUIFigure);
            app.Menu3Dplot = uimenu(app.ContextMenuPlots);
            app.Menu3Dplot.MenuSelectedFcn = createCallbackFcn(app, @Menu3DplotSelected, true);
            app.Menu3Dplot.Checked = 'on';
            app.Menu3Dplot.Text = '3D Plot';
            % Assign app.ContextMenuPlots
            app.PnlPlots.ContextMenu = app.ContextMenuPlots;
            % Show the figure after all components are created
            app.ConeRodModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConeRodModel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConeRodModelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ConeRodModelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeRodModelUIFigure)
        end
    end
end